classdef robot_ABB_IRB_2400 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ConfigurationButtonGroup  matlab.ui.container.ButtonGroup
        Button_2                  matlab.ui.control.RadioButton
        Button                    matlab.ui.control.RadioButton
        ViewButtonGroup           matlab.ui.container.ButtonGroup
        XYButton_2                matlab.ui.control.RadioButton
        YZButton                  matlab.ui.control.RadioButton
        XZButton                  matlab.ui.control.RadioButton
        DButton                   matlab.ui.control.RadioButton
        BLamp                     matlab.ui.control.Lamp
        NextPosCalButton          matlab.ui.control.StateButton
        NoteEditField             matlab.ui.control.EditField
        NoteEditFieldLabel        matlab.ui.control.Label
        PressEscLabel             matlab.ui.control.Label
        PressrLabel               matlab.ui.control.Label
        ResetButton               matlab.ui.control.Button
        ExitButton                matlab.ui.control.Button
        RollEditField             matlab.ui.control.NumericEditField
        RollEditFieldLabel        matlab.ui.control.Label
        PitchEditField            matlab.ui.control.NumericEditField
        PitchEditFieldLabel       matlab.ui.control.Label
        YawEditField              matlab.ui.control.NumericEditField
        YawEditFieldLabel         matlab.ui.control.Label
        ZmmEditField              matlab.ui.control.NumericEditField
        ZmmEditFieldLabel         matlab.ui.control.Label
        YmmEditField              matlab.ui.control.NumericEditField
        YmmEditFieldLabel         matlab.ui.control.Label
        XmmEditField              matlab.ui.control.NumericEditField
        XmmEditFieldLabel         matlab.ui.control.Label
        Q6SliderLabel             matlab.ui.control.Label
        Q6Slider                  matlab.ui.control.Slider
        Q6EditField               matlab.ui.control.NumericEditField
        Q6EditFieldLabel          matlab.ui.control.Label
        Q5SliderLabel             matlab.ui.control.Label
        Q5Slider                  matlab.ui.control.Slider
        Q5EditField               matlab.ui.control.NumericEditField
        Q5EditFieldLabel          matlab.ui.control.Label
        Q4SliderLabel             matlab.ui.control.Label
        Q4Slider                  matlab.ui.control.Slider
        Q4EditField               matlab.ui.control.NumericEditField
        Q4EditFieldLabel          matlab.ui.control.Label
        Q3SliderLabel             matlab.ui.control.Label
        Q3Slider                  matlab.ui.control.Slider
        Q3EditField               matlab.ui.control.NumericEditField
        Q3EditFieldLabel          matlab.ui.control.Label
        Q2SliderLabel             matlab.ui.control.Label
        Q2Slider                  matlab.ui.control.Slider
        Q2EditField               matlab.ui.control.NumericEditField
        Q2EditFieldLabel          matlab.ui.control.Label
        Q1EditField               matlab.ui.control.NumericEditField
        Q1EditFieldLabel          matlab.ui.control.Label
        InterfazGrficaRobotABBIRB2400Label  matlab.ui.control.Label
        Q1SliderLabel             matlab.ui.control.Label
        Q1Slider                  matlab.ui.control.Slider
    end

    
    properties (Access = public)
        q = zeros(1,6); % Guarda valores articulares de q1 - q6
        L0 = 6.15*0.1;  
        L1 = 1.00*0.1;
        L2 = 7.05*0.1;
        L3 = 1.35*0.1;
        L4 = 7.55*0.1;
        %L5 = 0.85*0.1;
        L5 = 0;
        robotabb ; 
        %qlimits = [-180 180; -100 110; -60 65; -200 200; -120 120; -400 400];
        %qlimits = [-180 180; -111 101; -66 61; -200 200; -120 120; -400 400]; %q2 y q3 son negativos por la convención que asignamos
        qlimits = [-180 180; -110 100; -65 60; -200 200; -120 120; -400 400]; %q2 y q3 son negativos por la convención que asignamos
        ws = [-2.5 2.5 -2.5 2.5 -1.6 3];
        cal_points = [855 1455; 360 2041; 541 693; 1351 -118; 400 -302; -1350 624; -53 1036];
        qs_pos_conf1 = [pi/4 -pi/6 pi/3 0 pi/4 pi/2; 3*pi/4 -pi/3 pi/12 pi/6 pi/3 pi/4];
        qs_pos_conf2 = [-pi/4 pi/3 pi/6 pi/3 0 -3*pi/4; 2*pi/3 pi/2 pi/4 3*pi/4 -pi/3 pi/2];
        aux = 1;
    end
    
    % métodos adiconales
    methods (Access = private)   
        function v_round = round_vec(app, vec)
            v_round = vec;
            for i = 1:max(size(vec))
                if abs(v_round(i)) < 0.00001
                    v_round(i) = 0;
                end
            end
        end
        
        function [] = update_limits(app)
            % Actualiza los límites de los campos numéricos y deslizadores
            % de q1-q6
%             for i = 1:size(app.qlimits,1)
%                 for j = 1:size(app.qlimits,2)
%                     if app.qlimits(i,j) < 1
%                         app.qlimits(i,j) -= 1;
%                     elseif app.qlimits(i,j) > 1
%                         app.qlimits(i,j) += 1;
%                     end
%                 end
%             end
            app.Q1Slider.Limits = app.qlimits(1,:);
            app.Q2Slider.Limits = app.qlimits(2,:);
            app.Q3Slider.Limits = app.qlimits(3,:);
            app.Q4Slider.Limits = app.qlimits(4,:);
            app.Q5Slider.Limits = app.qlimits(5,:);
            app.Q6Slider.Limits = app.qlimits(6,:);
            
            app.Q1EditField.Limits = app.qlimits(1,:);
            app.Q2EditField.Limits = app.qlimits(2,:);
            app.Q3EditField.Limits = app.qlimits(3,:);
            app.Q4EditField.Limits = app.qlimits(4,:);
            app.Q5EditField.Limits = app.qlimits(5,:);
            app.Q6EditField.Limits = app.qlimits(6,:);
        end
        
        function [] = update_pos(app)
            % Función actualiza los valores de la pose del efector final
            % x, y, z, roll, pitch y yaw
            T = app.robotabb.fkine(app.q);
            xyz = round(transl(T),3)*1000;
            rpy = round(rad2deg(tr2rpy(T)),4);
            app.XmmEditField.Value = xyz(1);
            app.YmmEditField.Value = xyz(2);
            app.ZmmEditField.Value = xyz(3);
            app.RollEditField.Value = double(rpy(1));
            app.PitchEditField.Value = double(rpy(2));
            app.YawEditField.Value = double(rpy(3));
        end
        
        function [] = update_qs(app)
            qs = rad2deg(app.q);
            qs = app.round_vec(qs);
            qs = round(qs,4);
%             for i =1:max(size(qs))
%                 if abs(qs(i)+min(app.qlimits(i)))<1
%                     qs(i) = min(app.qlimits(i));
%                 elseif abs(qs(i)-max(app.qlimits(i)))<1
%                     qs(i) = max(app.qlimits(i));
%                 end
%             end
            app.Q1EditField.Value = qs(1);
            app.Q2EditField.Value = qs(2);
            app.Q3EditField.Value = qs(3);
            app.Q4EditField.Value = qs(4);
            app.Q5EditField.Value = qs(5);
            app.Q6EditField.Value = qs(6);
            app.robotabb.plot(app.q);
        end
        
        function [] = update_qs_sliders(app)
            qs = rad2deg(app.q);
            qs = app.round_vec(qs);
            qs = round(qs,4);
            app.Q1Slider.Value = qs(1);
            app.Q2Slider.Value = qs(2);
            app.Q3Slider.Value = qs(3);
            app.Q4Slider.Value = qs(4);
            app.Q5Slider.Value = qs(5);
            app.Q6Slider.Value = qs(6);
        end
        
        function [] = place_graphics(app,fig)
            % Función que coloca de forma centrada la figura del robot
            % y de forma centrada el la GUI, siempre y cuando, la
            % resolución de la pantalla sea mayor a 1221x800 pixeles

            fig.Units = 'pixels';
            size_screen = get(0,'ScreenSize');%[left bottom width height]
            if size_screen(3)>1221 && size_screen(4) > 800
                api_pos = app.UIFigure.OuterPosition; %[left bottom width height]
                fig_pos = fig.OuterPosition;
                px_api = (size_screen(3)-800-api_pos(3))*0.5;
                py_api = (size_screen(4)-api_pos(4))*0.5;
                
                app.UIFigure.Position = [px_api py_api api_pos(3) api_pos(4)];
                px_fig = px_api+api_pos(3);
                py_fig = (size_screen(4)-fig_pos(4))*0.5;
                fig.Position = [px_fig py_fig 800 600];
            else
                disp('Resolución pequeña')
                fig.Position = [400 0 800 600];
                app.UIFigure.Position = [0 0 421  574];
            end
            
            fig.Visible = 'on';
            app.UIFigure.Visible = 'on';
        end
    
        function [p, r] = capture_pose(app)
            % Retorna la pose actual del efector final (posición y
            % orientación
            p = [app.XmmEditField.Value app.YmmEditField.Value ...
                app.ZmmEditField.Value];
            r = [app.RollEditField.Value app.PitchEditField.Value ...
                app.YawEditField.Value];
        end
        
        function [] = closeapp(app)
            close all
            clc
            delete(app.UIFigure)
        end
        
        function [] = resetear(app)
            app.q = zeros(1,6);
            
            app.Q1EditField.Value = 0;
            app.Q2EditField.Value = 0;
            app.Q3EditField.Value = 0;
            app.Q4EditField.Value = 0;
            app.Q5EditField.Value = 0;
            app.Q6EditField.Value = 0;
            
            app.Q1Slider.Value = 0;
            app.Q2Slider.Value = 0;
            app.Q3Slider.Value = 0;
            app.Q4Slider.Value = 0;
            app.Q5Slider.Value = 0;
            app.Q6Slider.Value = 0;
            
            app.NoteEditField.Value = '';
            app.BLamp.Color = 'green';
            app.robotabb.plot(app.q);
            update_pos(app);
        end
        
        function [out_lim] = q_is_in_limits(app)
            s = '';
            count = 0;
            for i = 1:max(size(app.q))
                if rad2deg(app.q(i)) < min(app.qlimits(i,:)) | ...
                        rad2deg(app.q(i)) > max(app.qlimits(i,:))
                    %s = strcat(s,' Me(',num2str(i),') ');
                    count = count +1;
%                 elseif rad2deg(app.q(i)) > max(app.qlimits(i,:))
%                     %s = strcat(s,' Ma(',num2str(i),') ');
%                     count = count +1;
                end
            end
            if count ~= 0
                app.NoteEditField.Value = 'Posición fuera del los límites articulares';
            end            
            out_lim = count == 0;
        end
        
        function [] = up_ikinematics(app)
            [p, r] = app.capture_pose();
            p = 0.001*p; %se pasa a metros la posición
            
            codo = 1;%codo arriba: +1, codo abajo: -1
            adelante = 1; % adelante: +1, atras: -1
            contador = 0;
            l1 = app.L0;
            l2 = app.L1;
            l3 = app.L2;
            l4 = sqrt((app.L3)^2+(app.L4)^2);
            xm = p(1); ym = p(2); zm = p(3);
            
            % Cálculo de q1
            q1 = atan2(ym,abs(xm));
            
            % Cálculo de q2
            l2 = l2*cos(q1);
            vpw = [xm zm] - [l2 l1];
            pw = norm(vpw);
            xp = vpw(1);
            zp = vpw(2);
            
            ca = ( (l3^2)+(pw^2)-(l4^2) ) / (2*l3*pw); %cos(\alpha)
            sa = sqrt(1-(ca^2)); %sin(\alpha)
            if abs(ca) > 1
                app.NoteEditField.Value = 'Fuera de los límites articulares de q2';
                a = 0;
                contador = contador + 1;
            else
                a = atan2(sa,ca);
            end
            
            b = atan2(-xp,zp);
            q2 = a + b;
            
            % Cálculo de q3
            cg = ((l3^2)+(l4^2)-(pw^2)) / (2*l3*l4); % cos(\gamma)
            sg = sqrt(1-(cg^2)); % sin(\gamma)
            disp(cg)
            if abs(cg) > 1
                app.NoteEditField.Value = 'Fuera de los límites articulares de q3';
                g = 0;
                contador = contador + 1;
            else
                g = atan2(sg,cg);
            end
            
            t = atan2(app.L3,app.L4); % \theta            
            q3 = g - (t + pi/2);
            
            app.q(1:3) = [q1 q2 q3];
            app.robotabb.plot(app.q);
            if contador == 0 && app.q_is_in_limits()
                app.update_qs();
                app.BLamp.Color = 'green';
            else
                app.BLamp.Color = 'red';
            end
%             if app.q_is_in_limits()
%                 app.update_qs();
%             end

%             disp(app.robotabb.ikine(T))
%             disp(app.robotabb.ikcon(T))
%             disp(app.robotabb.fkine(app.q))
%             app.q = app.robotabb.ikcon(T);
%             app.robotabb.plot(app.q);
        end
        
        function [] = up_ikinematics2(app)
            codo = 1;%codo arriba: +1, codo abajo: -1
            l1 = app.L0;
            l2 = app.L1;
            l3 = app.L2;
            l4 = sqrt((app.L3)^2+(app.L4)^2);
            
            [p, r] = app.capture_pose();
            p = 0.001*p; %se pasa a metros la posición
            xm = p(1); ym = p(2); zm = p(3);
            
            % Cálculo de q1
            q1 = atan2(ym,xm);
            
            % Cálculo de q2
            l2 = l2*cos(q1);
            pw = sqrt((xm-l2)^2+(zm-l1)^2);
            ca = ( (l3^2)+(pw^2)-(l4^2) ) / (2*l3*pw); %cos(\alpha)
            sa = sqrt(1-ca^2); %sin(\alpha)
            if abs(ca) > 1
                app.NoteEditField.Value = 'Fuera de los límites articulares de q2';
                a = 0;
            else
                a = atan2(sa,ca);
            end            
            
            sb = (-xm+l2)/pw; % sin(\beta)
            cb = sqrt(1-sb^2); %cos(\beta)
            
            if abs(sb) > 1
                app.NoteEditField.Value = 'Fuera de los límites articulares de q2';
                b = 0;
            else
                b = atan2(sb,cb);
            end
            
            q2 = a + b;
            
            % Cálculo de q3a
            cg = ((l3^2)+(l4^2)-(pw^2)) / (2*l3*l4); % cos(\gamma)
            sg = sqrt(1-cg^2); % sin(\gamma)
            disp(cg)
            if abs(cg) > 1
                app.NoteEditField.Value = 'Fuera de los límites articulares de q3';
                g = 0;
            else
                g = atan2(sg,cg);
            end
            
            
            t = atan2(app.L3,app.L4); % \theta
            
            q3 = g - (t + pi/2);
            
            disp(rad2deg([q1 q2 q3]))
            app.q(1:3) = app.round_vec([q1 q2 q3]);
            
            app.robotabb.plot(app.q);
            disp(rad2deg(app.q))
            app.NoteEditField.Value = num2str(rad2deg(app.q));
            %app.q_is_in_limits()
            %app.update_qs()

%             disp(app.robotabb.ikine(T))
%             disp(app.robotabb.ikcon(T))
%             disp(app.robotabb.fkine(app.q))
%             app.q = app.robotabb.ikcon(T);
%             app.robotabb.plot(app.q);
        end
        
        function [] = up_ikinematics3(app)
            contador = 0;
            codo = 1;%codo arriba: +1, codo abajo: -1
            l1 = app.L0;
            l2 = app.L1;
            l3 = app.L2;
            l4 = sqrt((app.L3)^2+(app.L4)^2);
            
            [p, r] = app.capture_pose();
            p = 0.001*p; %se pasa a metros la posición
            xm = p(1); ym = p(2); zm = p(3);
            
            % Cálculo de q1
            q1 = atan2(ym,xm)-pi;
            
            % Cálculo de q2
            l2 = l2*cos(q1);
            pw = sqrt((xm-l2)^2+(zm-l1)^2);
            ca = ( (l3^2)+(pw^2)-(l4^2) ) / (2*l3*pw); %cos(\alpha)
            sa1 = sqrt(1-ca^2); %sin(\alpha)
            sa2 = -sa1;
            if abs(ca) > 1
                app.NoteEditField.Value = 'Fuera de los límites articulares de q2';
                contador = contador +1;
                a = 0;
            else
                a = atan2(sa2,ca);
            end            

            cb = (zm-l1)/pw;
            sb1 = sqrt(1-cb^2);
            sb2 = -sb1;
            
            if abs(cb) > 1
                app.NoteEditField.Value = 'Fuera de los límites articulares de q2';
                contador = contador +1;
                b = 0;
            else
                b = atan2(sb1,cb);
            end
            
            aa = [atan2(sa1,ca) atan2(sa2,ca)];
            bb = [atan2(sb1,cb) atan2(sb2,cb)];
            
            q2 = a + b;
            
            % Cálculo de q3
            cg = ((l3^2)+(l4^2)-(pw^2)) / (2*l3*l4); % cos(\gamma)
            sg1 = sqrt(1-cg^2); % sin(\gamma)
            sg2 = -sg1;
            
            if abs(cg) > 1
                app.NoteEditField.Value = 'Fuera de los límites articulares de q3';
                contador = contador +1;
                g = 0;
            else
                g = atan2(sg1,cg);
            end
            
            
            t = atan2(app.L3,app.L4); % \theta
            
            q3 = g - (t + pi/2);
            app.q(1:3) = [q1 q2 q3];
            app.robotabb.plot(app.q);
            if contador == 0 && app.q_is_in_limits()
                app.update_qs();
                app.BLamp.Color = 'green';
            else
                app.BLamp.Color = 'red';
            end
%                 
%                 disp(rad2deg([q1 q2 q3]))
%                 app.q(1:3) = app.round_vec([q1 q2 q3]);
%                 
%                 app.robotabb.plot(app.q);
%                 disp(rad2deg(app.q))
%                 app.NoteEditField.Value = num2str(rad2deg(app.q));
            %app.q_is_in_limits()
            %app.update_qs()

%             disp(app.robotabb.ikine(T))
%             disp(app.robotabb.ikcon(T))
%             disp(app.robotabb.fkine(app.q))
%             app.q = app.robotabb.ikcon(T);
%             app.robotabb.plot(app.q);
        end
        
        function [qs] = get_ikine_conf1(app,T0ef)
            l1 = app.L0;
            l2 = app.L1;
            l3 = app.L2;
            l4 = sqrt((app.L3)^2+(app.L4)^2);
            l5 = 0.085; 
            [Ref,pef]=tr2rt(T0ef);
            
            %Posición muñeca
            pm=pef-l5*Ref(1:3,3);
            
            %q1
            q1=atan2(pm(2),pm(1));
            q1d=rad2deg(q1);
            
            pw=sqrt((pm(1)-l2*cos(q1))^2+(pm(2)-l2*sin(q1))^2+(pm(3)-l1)^2);
            
            %alpha
            ca=(l3^2+pw^2-l4^2)/(2*l3*pw);
            sa1=sqrt(1-ca^2);
            sa2=-sa1;
            a=atan2(sa1,ca);
            % a=atan2(sa2,ca);
            
            %beta
            sb=(pm(3)-l1)/pw;
            cb1=sqrt(1-sb^2);
            cb2=-cb1;
            b=atan2(sb,cb1);
            % b=atan2(sb,cb2);
            
            %q2
            q2=-pi/2+a+b;
            q2d=rad2deg(q2);
            
            %gamma
            cg=(l3^2+l4^2-pw^2)/(2*l3*l4);
            sg1=sqrt(1-cg^2);
            sg2=-sg1;
            g=atan2(sg1,cg);
            %g=atan2(sg2,cg);
            
            %theta
            theta=atan(1.35/7.55);
            
            %q3
            q3=g-pi/2-theta;
            q3d=rad2deg(q3);
            
            %Orientación del efector final
            R06=Ref;
            [R03,~]=tr2rt(app.robotabb.A([1 2 3],[q1 q2 q3]));
            R36=R03\R06;
            
%             syms q4 q5 q6
%             th=1e-10;
%             
%             R34=mapSymType(L(4).A(q4),'rational',@(x) piecewise(abs(x)<=th,0,x));
%             R45=mapSymType(L(5).A(q5),'rational',@(x) piecewise(abs(x)<=th,0,x));
%             R56=mapSymType(L(6).A(q6),'rational',@(x) piecewise(abs(x)<=th,0,x));
            
            %q4
            q4=atan2(R36(3,3),R36(1,3));
            
            %q5
            q5=atan2(cos(q4)*R36(1,3)+sin(q4)*R36(3,3),-R36(2,3));
            
            %q6
            q6=atan2(-sin(q4)*R36(1,1)+cos(q4)*R36(3,1),-sin(q4)*R36(1,2)+cos(q4)*R36(3,2));
            
            qs = [q1 q2 q3 q4 q5 q6];
        end
    
        function [qs] = get_ikine_conf2(app,T0ef)
            l1 = app.L0;
            l2 = app.L1;
            l3 = app.L2;
            l4 = sqrt((app.L3)^2+(app.L4)^2);
            l5 = 0.085; 
            
            [Ref,pef]=tr2rt(T0ef);

            %Posición muñeca
            pm=pef-l5*Ref(1:3,3);
            
            %delta
            d=atan2(pm(2),pm(1));
            disp(rad2deg(d))
            %q1
            q1=d-pi;
            q1d=rad2deg(q1);
            
            pw=sqrt((pm(1)-l2*cos(q1))^2+(pm(2)-l2*sin(q1))^2+(pm(3)-l1)^2);
            
            %alpha
            ca=(l3^2+pw^2-l4^2)/(2*l3*pw);
            sa1=sqrt(1-ca^2);
            sa2=-sa1;
            a=atan2(sa1,ca);
            % a=atan2(sa2,ca);
            
            %beta
            cb=(pm(3)-l1)/pw;
            sb1=sqrt(1-cb^2);
            sb2=-sb1;
            b=atan2(sb1,cb);
            % b=atan2(sb2,cb);
            
            %q2
            q2=a+b;
            q2d=rad2deg(q2);
            
            %gamma
            cg=(l3^2+l4^2-pw^2)/(2*l3*l4);
            sg1=sqrt(1-cg^2);
            sg2=-sg1;
            g=atan2(sg1,cg);
            %g=atan2(sg2,cg);
            
            %theta
            theta=atan(1.35/7.55);
            
            %q3
            q3=g-pi/2-theta;
            q3d=rad2deg(q3);
            
            %Orientación del efector final
            R06=Ref;
            [R03,~]=tr2rt(app.robotabb.A([1 2 3],[q1 q2 q3]));
            R36=R03\R06;
            %q4
            q4=atan2(R36(3,3),R36(1,3));
            
            %q5
            q5=atan2(cos(q4)*R36(1,3)+sin(q4)*R36(3,3),-R36(2,3));
            
            %q6
            q6=atan2(-sin(q4)*R36(1,1)+cos(q4)*R36(3,1),-sin(q4)*R36(1,2)+cos(q4)*R36(3,2));
            
            qs = [q1 q2 q3 q4 q5 q6];
        end
        
        function [] = clean_plot(app)
            clf
            fig = gcf;
            app.robotabb.plot(app.q)                    
            hold on
            trplot(eye(4),'rgb','thick',2,'frame','O','length',0.3)
            app.place_graphics(fig)            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Función de configruación de inicio, se establecen los límites
            % en los deslizadores, se crea el objeto del robot
            app.UIFigure.Visible = 'off';
            update_limits(app)
            
            L(1) = Link('revolute','alpha',     0,'a',     0,'d',app.L0,'offset',0,'modified');
            L(2) = Link('revolute','alpha',  pi/2,'a',app.L1,'d',     0,'offset',pi/2,'modified');
            L(3) = Link('revolute','alpha',     0,'a',app.L2,'d',     0,'offset',0,'modified');
            L(4) = Link('revolute','alpha',  pi/2,'a',app.L3,'d',app.L4,'offset',0,'modified');
            L(5) = Link('revolute','alpha', -pi/2,'a',     0,'d',     0,'offset',0,'modified');
            L(6) = Link('revolute','alpha',  pi/2,'a',     0,'d',app.L5,'offset',0,'modified');
            
            
            plot_options = {'workspace',app.ws,'scale',.5};%,'view',[30 30],'tilesize',2,'ortho', 'lightpos',[2 2 10] };
            app.robotabb = SerialLink(L,'name','ABB IRB2400','plotopt',plot_options);
            app.robotabb.tool = transl(0,0,0.085);
            
            fig = figure('Name','ROBOT: ABB IRB 2400','NumberTitle','off', ...
                'Position',[200 0 800 600],'visible','off');
            
            app.robotabb.plot(app.q)
            hold on
            trplot(eye(4),'rgb','thick',2,'frame','O','length',0.3)
            view([30 30])
            update_pos(app)            
            place_graphics(app,fig)
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.closeapp();
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Deja los valores articulares en cero, actualizando los
            % valores de los campos de texto y los deslizadores a cero
            app.resetear();
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            % captura teclado
            % r: resetear valores en la app
            % escape: cerrar la app
            key = event.Key;
            switch key
                case 'escape'
                    app.closeapp()
                case 'r'
                    app.resetear()
            end
        end

        % Value changed function: Q1EditField
        function Q1EditFieldValueChanged(app, event)
            app.q(1) = deg2rad(app.Q1EditField.Value);
            update_pos(app)
            app.robotabb.plot(app.q);
        end

        % Value changed function: Q2EditField
        function Q2EditFieldValueChanged(app, event)
            app.q(2) = deg2rad(app.Q2EditField.Value);
            update_pos(app)
            app.robotabb.plot(app.q);
        end

        % Value changed function: Q3EditField
        function Q3EditFieldValueChanged(app, event)
            app.q(3) = deg2rad(app.Q3EditField.Value);
            update_pos(app)
            app.robotabb.plot(app.q);
        end

        % Value changed function: Q4EditField
        function Q4EditFieldValueChanged(app, event)
            app.q(4) = deg2rad(app.Q4EditField.Value);
            update_pos(app)
            app.robotabb.plot(app.q);
        end

        % Value changed function: Q5EditField
        function Q5EditFieldValueChanged(app, event)
            app.q(5) = deg2rad(app.Q5EditField.Value);
            update_pos(app)
            app.robotabb.plot(app.q);
        end

        % Callback function
        function Q6EditFieldValueChanged(app, event)
            app.q(6) = deg2rad(app.Q6EditField.Value);
            update_pos(app)
            app.robotabb.plot(app.q);
        end

        % Value changing function: Q1Slider
        function Q1SliderValueChanging(app, event)
            val = event.Value;
            app.q(1) = deg2rad(val);
            app.Q1EditField.Value = double(val);
            app.robotabb.plot(app.q);
            update_pos(app)
        end

        % Value changing function: Q2Slider
        function Q2SliderValueChanging(app, event)
            val = event.Value;
            app.q(2) = deg2rad(val);
            app.Q2EditField.Value = round(val,2);
            app.robotabb.plot(app.q);
            update_pos(app)
        end

        % Value changing function: Q3Slider
        function Q3SliderValueChanging(app, event)
            val = event.Value;
            app.q(3) = deg2rad(val);
            app.Q3EditField.Value = double(val);
            app.robotabb.plot(app.q);
            update_pos(app)
        end

        % Value changing function: Q4Slider
        function Q4SliderValueChanging(app, event)
            val = event.Value;
            app.q(4) = deg2rad(val);
            app.Q4EditField.Value = double(val);
            app.robotabb.plot(app.q);
            update_pos(app)
        end

        % Value changing function: Q5Slider
        function Q5SliderValueChanging(app, event)
            val = event.Value;
            app.q(5) = deg2rad(val);
            app.Q5EditField.Value = double(val);
            app.robotabb.plot(app.q);
            update_pos(app)
        end

        % Value changing function: Q6Slider
        function Q6SliderValueChanging(app, event)
            val = event.Value;
            app.q(6) = deg2rad(val);
            app.Q6EditField.Value = double(val);
            app.robotabb.plot(app.q);
            update_pos(app)
        end

        % Value changed function: XmmEditField
        function XmmEditFieldValueChanged(app, event)
            app.up_ikinematics();
        end

        % Value changed function: YmmEditField
        function YmmEditFieldValueChanged(app, event)
            app.up_ikinematics();
        end

        % Value changed function: ZmmEditField
        function ZmmEditFieldValueChanged(app, event)
            app.up_ikinematics()
        end

        % Callback function
        function SliderValueChanging(app, event)
            app.up_ikinematics();
        end

        % Value changed function: NextPosCalButton
        function NextPosCalButtonValueChanged(app, event)
            value = app.NextPosCalButton.Value;
            if app.aux + 1 > 2
                app.aux = 1;
            else
                app.aux = app.aux + 1;
            end
            
            disp(app.qlimits')
            switch app.ConfigurationButtonGroup.SelectedObject.Text
                case '1'
                    %Toef = app.robotabb.fkine(app.qs_pos_conf1(app.aux,:));
                    Toef = transl(0.5, 0.1, 1)*trotx(30,'deg')*troty(60,'deg')*trotz(10,'deg');
                    aqs = app.get_ikine_conf1(Toef);
                    app.q = aqs;
                    app.NoteEditField.Value = 'Configuración 1 seleccionada';
                    disp('Configuración 1 seleccionada')
                    disp(rad2deg(app.qs_pos_conf1(app.aux,:)))
                case '2'
                    Toef = app.robotabb.fkine(app.qs_pos_conf2(app.aux,:));
                    aqs = app.get_ikine_conf2(Toef);
                    app.q = aqs;
                    app.NoteEditField.Value = 'Configuración 2 seleccionada';
                    disp('Configuración 2 seleccionada')
                    disp('qs entrantes')
                    disp(rad2deg(app.qs_pos_conf2(app.aux,:)))                    
            end
            disp('qs antes de validar')
            disp(rad2deg(app.q))
            disp(app.q(1))
            if rad2deg(app.q(1)) < -180
                disp('se actualiza el q')
                app.q(1) = 2*pi+app.q(1);
            end
            disp('qs obtenidos')
            disp(rad2deg(app.q))
            
            app.update_pos();
            app.update_qs();
            app.update_qs_sliders();
            app.robotabb.plot(app.q);
            
            
            %app.NoteEditField.Value = num2str(round(rad2deg(aqs),4));
%             app.XmmEditField.Value = app.cal_points(app.aux,1);
%             app.ZmmEditField.Value = app.cal_points(app.aux,2);
%             if app.aux + 1> max(size(app.cal_points))
%                 app.aux = 1;
%             else
%                 app.aux = app.aux + 1;
%             end
%             app.up_ikinematics3();
        end

        % Selection changed function: ViewButtonGroup
        function ViewButtonGroupSelectionChanged(app, event)
            selectedButton = app.ViewButtonGroup.SelectedObject;
            texto = selectedButton.Text;
            [p,r] = app.capture_pose()
            p = 0.001*p;
            app.NoteEditField.Value = num2str(p);
            switch texto
                case 'XY'                    
                    app.clean_plot();
                    disp('seleccionó XY')
                    view([0 0 2])
                    plot([-2.5 p(1)],[p(2) p(2)],'g--') %horizontal
                    plot([p(1) p(1)],[-2.5 p(2)],'r--') %vertical
                case 'XZ'
                    app.clean_plot();
                    disp('seleccionó XZ')
                    view([0 2 0])
%                     %quiver3()
%                     line([p(2) 1 p(2)],[-1.5 1 p(3)])
% %                     a = plot([-2.5 p(2)],[p(3) p(3)],'b--'); %horizontal
% %                     b = plot([p(2) p(2)],[-1.5 p(3)],'r--'); %vertical
%                     direction = [1 0 0];
%                     direction1 = [0 0 1];
% %                     rotate(a,direction,90)
% %                     rotate(b,direction,90)
% %                     rotate(a,direction1,90)
% %                     rotate(b,direction1,90)
                    
                case 'YZ'
                    app.clean_plot();
                    disp('seleccionó YZ')
                    view([2 0 0])
                case '3D'
                    app.clean_plot();
                    disp('seleccionó YZ')
                    view([30 30])
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Q1Slider = uislider(app.UIFigure);
            app.Q1Slider.Limits = [-180 180];
            app.Q1Slider.ValueChangingFcn = createCallbackFcn(app, @Q1SliderValueChanging, true);
            app.Q1SliderLabel = uilabel(app.UIFigure);
            app.Q1SliderLabel.Text = 'Q1  ';
            app.InterfazGrficaRobotABBIRB2400Label = uilabel(app.UIFigure);
            app.InterfazGrficaRobotABBIRB2400Label.Text = {'Interfaz Gráfica'; 'Robot ABB IRB 2400'};
            app.Q1EditFieldLabel = uilabel(app.UIFigure);
            app.Q1EditFieldLabel.Text = 'Q1';
            app.Q1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q1EditField.ValueChangedFcn = createCallbackFcn(app, @Q1EditFieldValueChanged, true);
            app.Q2EditFieldLabel = uilabel(app.UIFigure);
            app.Q2EditFieldLabel.Text = 'Q2';
            app.Q2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q2EditField.ValueChangedFcn = createCallbackFcn(app, @Q2EditFieldValueChanged, true);
            app.Q2Slider = uislider(app.UIFigure);
            app.Q2Slider.Limits = [-180 180];
            app.Q2Slider.ValueChangingFcn = createCallbackFcn(app, @Q2SliderValueChanging, true);
            app.Q2SliderLabel = uilabel(app.UIFigure);
            app.Q2SliderLabel.Text = 'Q2';
            app.Q3EditFieldLabel = uilabel(app.UIFigure);
            app.Q3EditFieldLabel.Text = 'Q3';
            app.Q3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q3EditField.ValueChangedFcn = createCallbackFcn(app, @Q3EditFieldValueChanged, true);
            app.Q3Slider = uislider(app.UIFigure);
            app.Q3Slider.Limits = [-180 180];
            app.Q3Slider.ValueChangingFcn = createCallbackFcn(app, @Q3SliderValueChanging, true);
            app.Q3SliderLabel = uilabel(app.UIFigure);
            app.Q3SliderLabel.Text = 'Q3';
            app.Q4EditFieldLabel = uilabel(app.UIFigure);
            app.Q4EditFieldLabel.Text = 'Q4';
            app.Q4EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q4EditField.ValueChangedFcn = createCallbackFcn(app, @Q4EditFieldValueChanged, true);
            app.Q4Slider = uislider(app.UIFigure);
            app.Q4Slider.Limits = [-180 180];
            app.Q4Slider.ValueChangingFcn = createCallbackFcn(app, @Q4SliderValueChanging, true);
            app.Q4SliderLabel = uilabel(app.UIFigure);
            app.Q4SliderLabel.Text = 'Q4';
            app.Q5EditFieldLabel = uilabel(app.UIFigure);
            app.Q5EditFieldLabel.Text = 'Q5';
            app.Q5EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q5EditField.ValueChangedFcn = createCallbackFcn(app, @Q5EditFieldValueChanged, true);
            app.Q5Slider = uislider(app.UIFigure);
            app.Q5Slider.Limits = [-180 180];
            app.Q5Slider.ValueChangingFcn = createCallbackFcn(app, @Q5SliderValueChanging, true);
            app.Q5SliderLabel = uilabel(app.UIFigure);
            app.Q5SliderLabel.Text = 'Q5';
            app.Q6EditFieldLabel = uilabel(app.UIFigure);
            app.Q6EditFieldLabel.Text = 'Q6';
            app.Q6EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q6Slider = uislider(app.UIFigure);
            app.Q6Slider.Limits = [-180 180];
            app.Q6Slider.ValueChangingFcn = createCallbackFcn(app, @Q6SliderValueChanging, true);
            app.Q6SliderLabel = uilabel(app.UIFigure);
            app.Q6SliderLabel.Text = 'Q6';
            app.XmmEditFieldLabel = uilabel(app.UIFigure);
            app.XmmEditFieldLabel.Text = 'X [mm]';
            app.XmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.XmmEditField.ValueChangedFcn = createCallbackFcn(app, @XmmEditFieldValueChanged, true);
            app.YmmEditFieldLabel = uilabel(app.UIFigure);
            app.YmmEditFieldLabel.Text = 'Y [mm]';
            app.YmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.YmmEditField.ValueChangedFcn = createCallbackFcn(app, @YmmEditFieldValueChanged, true);
            app.ZmmEditFieldLabel = uilabel(app.UIFigure);
            app.ZmmEditFieldLabel.Text = 'Z [mm]';
            app.ZmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZmmEditField.ValueChangedFcn = createCallbackFcn(app, @ZmmEditFieldValueChanged, true);
            app.YawEditFieldLabel = uilabel(app.UIFigure);
            app.YawEditFieldLabel.Text = 'Yaw [°]';
            app.YawEditField = uieditfield(app.UIFigure, 'numeric');
            app.YawEditField.Editable = 'off';
            app.PitchEditFieldLabel = uilabel(app.UIFigure);
            app.PitchEditFieldLabel.Text = 'Pitch [°]';
            app.PitchEditField = uieditfield(app.UIFigure, 'numeric');
            app.PitchEditField.Editable = 'off';
            app.RollEditFieldLabel = uilabel(app.UIFigure);
            app.RollEditFieldLabel.Text = 'Roll [°]';
            app.RollEditField = uieditfield(app.UIFigure, 'numeric');
            app.RollEditField.Editable = 'off';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PressrLabel = uilabel(app.UIFigure);
            app.PressrLabel.Text = 'Press "r"';
            app.PressEscLabel = uilabel(app.UIFigure);
            app.PressEscLabel.Text = 'Press "Esc"';
            app.NoteEditFieldLabel = uilabel(app.UIFigure);
            app.NoteEditFieldLabel.Text = 'Note';
            app.NoteEditField = uieditfield(app.UIFigure, 'text');
            app.NextPosCalButton = uibutton(app.UIFigure, 'state');
            app.NextPosCalButton.ValueChangedFcn = createCallbackFcn(app, @NextPosCalButtonValueChanged, true);
            app.NextPosCalButton.Text = 'Next Pos Cal';
            app.BLamp = uilamp(app.UIFigure);
            app.ViewButtonGroup = uibuttongroup(app.UIFigure);
            app.ViewButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ViewButtonGroupSelectionChanged, true);
            app.ViewButtonGroup.Title = 'View';
            app.DButton = uiradiobutton(app.ViewButtonGroup);
            app.DButton.Text = '3D';
            app.DButton.Value = true;
            app.XZButton = uiradiobutton(app.ViewButtonGroup);
            app.XZButton.Text = 'XZ';
            app.YZButton = uiradiobutton(app.ViewButtonGroup);
            app.YZButton.Text = 'YZ';
            app.XYButton_2 = uiradiobutton(app.ViewButtonGroup);
            app.XYButton_2.Text = 'XY';
            app.ConfigurationButtonGroup = uibuttongroup(app.UIFigure);
            app.ConfigurationButtonGroup.Title = 'Configuration';
            app.Button = uiradiobutton(app.ConfigurationButtonGroup);
            app.Button.Text = '1';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.ConfigurationButtonGroup);
            app.Button_2.Text = '2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robot_ABB_IRB_2400

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end